classdef SpectralGuidedWaves < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MeshTab                     matlab.ui.container.Tab
        MeshFig                     matlab.ui.control.UIAxes
        ActuatorcoordinatesLabel    matlab.ui.control.Label
        xpztEditFieldLabel          matlab.ui.control.Label
        xpztEditField               matlab.ui.control.NumericEditField
        PlateLengthEditFieldLabel   matlab.ui.control.Label
        PlateLengthEditField        matlab.ui.control.NumericEditField
        PlateWidthEditFieldLabel    matlab.ui.control.Label
        PlateWidthEditField         matlab.ui.control.NumericEditField
        ypztEditFieldLabel          matlab.ui.control.Label
        ypztEditField               matlab.ui.control.NumericEditField
        NxEditFieldLabel            matlab.ui.control.Label
        NxEditField                 matlab.ui.control.NumericEditField
        NyEditFieldLabel            matlab.ui.control.Label
        NyEditField                 matlab.ui.control.NumericEditField
        NumberofcorrespondingintepolationpointsLabel  matlab.ui.control.Label
        DelaminationLabel           matlab.ui.control.Label
        DelaminationcentercoordinatesLabel  matlab.ui.control.Label
        xCenterEditFieldLabel       matlab.ui.control.Label
        xCenterEditField            matlab.ui.control.NumericEditField
        yCenterEditFieldLabel       matlab.ui.control.Label
        yCenterEditField            matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        bEditField                  matlab.ui.control.NumericEditField
        DelaminationsemimajorandsemiminoraxislengthLabel  matlab.ui.control.Label
        pztradiusEditFieldLabel     matlab.ui.control.Label
        pztradiusEditField          matlab.ui.control.NumericEditField
        DelaminationRotationAngledegEditFieldLabel  matlab.ui.control.Label
        DelaminationRotationAngledegEditField  matlab.ui.control.NumericEditField
        CreateMeshButton            matlab.ui.control.Button
        MeshfilenameEditFieldLabel  matlab.ui.control.Label
        MeshfilenameEditField       matlab.ui.control.EditField
        MaterialsTab                matlab.ui.container.Tab
    end


    properties (Access = private)
        modelfolder = 'flat_shell'; % name of folder
        modelname = 'GUI'; 
        shape_order = 5; % element shape function order, Number of nodes in one direction is shape_order+1
        CharacteristicLengthFactor = 0.08; % GMSH options
        CharacteristicLengthMin = 0.001; 
        CharacteristicLengthMax = 0.2;
        Smoothing = 1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MeshFig.Visible = 'off';
        end

        % Button pushed function: CreateMeshButton
        function CreateMeshButtonPushed(app, event)
            % prepare model output path
            figure_output_path = prepare_figure_paths(app.modelfolder,app.modelname);
            L = app.PlateLengthEditField.Value;
            W = app.PlateWidthEditField.Value;
            a= app.aEditField.Value;
            b= app.bEditField.Value;
            xCenter = app.xCenterEditField.Value;
            yCenter = app.yCenterEditField.Value;
            rotAngle = app.DelaminationRotationAngledegEditField.Value;
            r = app.pztradiusEditField.Value;
            xpzt = app.xpztEditField.Value;
            ypzt = app.ypztEditField.Value;
            mesh_filename = app.MeshfilenameEditField.Value;
            figfilename = [figure_output_path,mesh_filename];
            % create mesh
            [msh,nodes,coords,den_under,den_above,I_G,I_L] = automesh_delam_GUI...
                            (L,W,a,b,xCenter,yCenter,rotAngle,r,xpzt,ypzt,app.shape_order,app.CharacteristicLengthFactor,app.CharacteristicLengthMin,app.CharacteristicLengthMax,app.Smoothing,mesh_filename,app.modelfolder);
            
            % plot mesh figure in GUI
            app.MeshFig.Visible = 'on';
            X=msh.POS(:,1);
            Y=msh.POS(:,2);
            nRegions = max(msh.QUADS(:,5));
            nbElements = size(msh.QUADS,1);
            colour = 'rgybmc';
            c=1;
            for k=1:nRegions
                c1=c;
                while(c <= nbElements && msh.QUADS(c,5)==k)
                    c=c+1;
                end 
                qnodes = msh.QUADS(c1:c-1,1:4);
                patch(app.MeshFig,'faces',qnodes,'vertices',[X Y],'facecolor',colour(k));
                hold(app.MeshFig);
            end
            axis(app.MeshFig, 'equal');
            axis(app.MeshFig,[msh.MIN(1) msh.MAX(1) msh.MIN(2)  msh.MAX(2)]);
            % plot invisible figure and save png file
            plot_mesh_GUI(msh,figfilename);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MeshTab = uitab(app.TabGroup);
            app.MeshTab.Title = 'Mesh';
            app.MeshFig = uiaxes(app.MeshTab);
            title(app.MeshFig, 'Mesh')
            app.ActuatorcoordinatesLabel = uilabel(app.MeshTab);
            app.ActuatorcoordinatesLabel.Text = 'Actuator coordinates';
            app.xpztEditFieldLabel = uilabel(app.MeshTab);
            app.xpztEditFieldLabel.Text = 'xpzt';
            app.xpztEditField = uieditfield(app.MeshTab, 'numeric');
            app.xpztEditField.Limits = [0 2];
            app.xpztEditField.Value = 0.25;
            app.PlateLengthEditFieldLabel = uilabel(app.MeshTab);
            app.PlateLengthEditFieldLabel.Text = 'Plate Length';
            app.PlateLengthEditField = uieditfield(app.MeshTab, 'numeric');
            app.PlateLengthEditField.LowerLimitInclusive = 'off';
            app.PlateLengthEditField.UpperLimitInclusive = 'off';
            app.PlateLengthEditField.Limits = [0.01 2];
            app.PlateLengthEditField.Value = 0.5;
            app.PlateWidthEditFieldLabel = uilabel(app.MeshTab);
            app.PlateWidthEditFieldLabel.Text = 'Plate Width';
            app.PlateWidthEditField = uieditfield(app.MeshTab, 'numeric');
            app.PlateWidthEditField.LowerLimitInclusive = 'off';
            app.PlateWidthEditField.UpperLimitInclusive = 'off';
            app.PlateWidthEditField.Limits = [0.01 2];
            app.PlateWidthEditField.Value = 0.5;
            app.ypztEditFieldLabel = uilabel(app.MeshTab);
            app.ypztEditFieldLabel.Text = 'ypzt';
            app.ypztEditField = uieditfield(app.MeshTab, 'numeric');
            app.ypztEditField.LowerLimitInclusive = 'off';
            app.ypztEditField.UpperLimitInclusive = 'off';
            app.ypztEditField.Limits = [0 2];
            app.ypztEditField.Value = 0.25;
            app.NxEditFieldLabel = uilabel(app.MeshTab);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxEditField = uieditfield(app.MeshTab, 'numeric');
            app.NxEditField.LowerLimitInclusive = 'off';
            app.NxEditField.UpperLimitInclusive = 'off';
            app.NxEditField.Limits = [100 1024];
            app.NxEditField.ValueDisplayFormat = '%.0f';
            app.NxEditField.Value = 500;
            app.NyEditFieldLabel = uilabel(app.MeshTab);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.MeshTab, 'numeric');
            app.NyEditField.LowerLimitInclusive = 'off';
            app.NyEditField.UpperLimitInclusive = 'off';
            app.NyEditField.Limits = [100 1024];
            app.NyEditField.ValueDisplayFormat = '%.0f';
            app.NyEditField.Value = 500;
            app.NumberofcorrespondingintepolationpointsLabel = uilabel(app.MeshTab);
            app.NumberofcorrespondingintepolationpointsLabel.Text = 'Number of corresponding intepolation points';
            app.DelaminationLabel = uilabel(app.MeshTab);
            app.DelaminationLabel.Text = 'Delamination';
            app.DelaminationcentercoordinatesLabel = uilabel(app.MeshTab);
            app.DelaminationcentercoordinatesLabel.Text = 'Delamination center coordinates';
            app.xCenterEditFieldLabel = uilabel(app.MeshTab);
            app.xCenterEditFieldLabel.Text = 'xCenter';
            app.xCenterEditField = uieditfield(app.MeshTab, 'numeric');
            app.xCenterEditField.Limits = [0 2];
            app.xCenterEditField.Value = 0.25;
            app.yCenterEditFieldLabel = uilabel(app.MeshTab);
            app.yCenterEditFieldLabel.Text = 'yCenter';
            app.yCenterEditField = uieditfield(app.MeshTab, 'numeric');
            app.yCenterEditField.LowerLimitInclusive = 'off';
            app.yCenterEditField.UpperLimitInclusive = 'off';
            app.yCenterEditField.Limits = [0 2];
            app.yCenterEditField.Value = 0.375;
            app.aEditFieldLabel = uilabel(app.MeshTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.MeshTab, 'numeric');
            app.aEditField.Limits = [0.001 0.25];
            app.aEditField.Value = 0.02;
            app.bEditFieldLabel = uilabel(app.MeshTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.MeshTab, 'numeric');
            app.bEditField.LowerLimitInclusive = 'off';
            app.bEditField.UpperLimitInclusive = 'off';
            app.bEditField.Limits = [0.001 0.25];
            app.bEditField.Value = 0.01;
            app.DelaminationsemimajorandsemiminoraxislengthLabel = uilabel(app.MeshTab);
            app.DelaminationsemimajorandsemiminoraxislengthLabel.Text = 'Delamination semi-major and semi-minor axis length';
            app.pztradiusEditFieldLabel = uilabel(app.MeshTab);
            app.pztradiusEditFieldLabel.Text = 'pzt radius';
            app.pztradiusEditField = uieditfield(app.MeshTab, 'numeric');
            app.pztradiusEditField.Limits = [0.001 0.025];
            app.pztradiusEditField.Value = 0.005;
            app.DelaminationRotationAngledegEditFieldLabel = uilabel(app.MeshTab);
            app.DelaminationRotationAngledegEditFieldLabel.Text = 'Delamination Rotation Angle [deg]';
            app.DelaminationRotationAngledegEditField = uieditfield(app.MeshTab, 'numeric');
            app.DelaminationRotationAngledegEditField.Limits = [0 179];
            app.DelaminationRotationAngledegEditField.Value = 30;
            app.CreateMeshButton = uibutton(app.MeshTab, 'push');
            app.CreateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMeshButtonPushed, true);
            app.CreateMeshButton.Text = 'Create Mesh';
            app.MeshfilenameEditFieldLabel = uilabel(app.MeshTab);
            app.MeshfilenameEditFieldLabel.Text = 'Mesh file name';
            app.MeshfilenameEditField = uieditfield(app.MeshTab, 'text');
            app.MeshfilenameEditField.Value = 'testmesh';
            app.MaterialsTab = uitab(app.TabGroup);
            app.MaterialsTab.Title = 'Materials';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SpectralGuidedWaves

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end